classdef ARES < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainGrid                        matlab.ui.container.GridLayout
        MainTabs                        matlab.ui.container.TabGroup
        InfoTab                         matlab.ui.container.Tab
        InfoGridLayout                  matlab.ui.container.GridLayout
        README                          matlab.ui.control.Hyperlink
        AntTutorial                     matlab.ui.control.Hyperlink
        PATutorial                      matlab.ui.control.Hyperlink
        Author                          matlab.ui.control.Label
        Repository                      matlab.ui.control.Hyperlink
        Relase                          matlab.ui.control.Label
        AppName                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PowerAmplifierTab               matlab.ui.container.Tab
        PAGridLayout                    matlab.ui.container.GridLayout
        PASettingsPanel                 matlab.ui.container.TabGroup
        InstrumentsTab                  matlab.ui.container.Tab
        PAInstrumentGridLayout          matlab.ui.container.GridLayout
        PADisconnectButton              matlab.ui.control.Button
        MeasurementDelaysEditFieldLabel  matlab.ui.control.Label
        SpectrumAnalyzerLabel           matlab.ui.control.Label
        PAConnectButton                 matlab.ui.control.Button
        PAMeasurementDelayValueField    matlab.ui.control.NumericEditField
        SpectrumAnalyzerDropDown        matlab.ui.control.DropDown
        SignalGeneratorLabel            matlab.ui.control.Label
        PowerSupplyBLabel               matlab.ui.control.Label
        PowerSupplyALabel               matlab.ui.control.Label
        SpectrumAnalyzerStatusLabel     matlab.ui.control.Label
        SignalGeneratorDropDown         matlab.ui.control.DropDown
        SignalGeneratorStatusLabel      matlab.ui.control.Label
        PowerSupplyBDropDown            matlab.ui.control.DropDown
        PowerSupplyBStatusLabel         matlab.ui.control.Label
        PowerSupplyADropDown            matlab.ui.control.DropDown
        PowerSupplyAStatusLabel         matlab.ui.control.Label
        PowerSupplyTab                  matlab.ui.container.Tab
        PAPSUGridLayout                 matlab.ui.container.GridLayout
        StartVoltage                    matlab.ui.control.NumericEditField
        StartVoltageVEditFieldLabel     matlab.ui.control.Label
        StopVoltage                     matlab.ui.control.NumericEditField
        StopVoltageVEditFieldLabel      matlab.ui.control.Label
        StepVoltage                     matlab.ui.control.NumericEditField
        StepVoltageVEditFieldLabel      matlab.ui.control.Label
        SupplyChannelDropDownLabel      matlab.ui.control.Label
        SupplyModeLabel                 matlab.ui.control.Label
        LimitingCurrentALabel           matlab.ui.control.Label
        SupplyCurrent                   matlab.ui.control.NumericEditField
        SingleSweepSwitch               matlab.ui.control.Switch
        DrainGateSwitch                 matlab.ui.control.Switch
        PowerSupplyChannelDropDown      matlab.ui.control.DropDown
        PowerSupplyModeDropDown         matlab.ui.control.DropDown
        SignalAnalysisTab               matlab.ui.container.Tab
        PASAGridLayout                  matlab.ui.container.GridLayout
        StepFrequency                   matlab.ui.control.NumericEditField
        StepFrequencyMHzEditFieldLabel  matlab.ui.control.Label
        SpectrumAnalyzerSettingsLabel   matlab.ui.control.Label
        SignalGeneratorSettingsLabel    matlab.ui.control.Label
        MeasurementTypeDropDownLabel    matlab.ui.control.Label
        ReferenceLevelValueField        matlab.ui.control.NumericEditField
        ReferenceLevelNameField         matlab.ui.control.Label
        SweepPointsValueField           matlab.ui.control.NumericEditField
        SweepPointsNameField            matlab.ui.control.Label
        SpanValueField                  matlab.ui.control.NumericEditField
        SpanNameField                   matlab.ui.control.Label
        PowerStep                       matlab.ui.control.NumericEditField
        PowerStepdBmEditField           matlab.ui.control.Label
        EndPower                        matlab.ui.control.NumericEditField
        EndPowerdBmEditFieldLabel       matlab.ui.control.Label
        StartPower                      matlab.ui.control.NumericEditField
        StartPowerdBmEditFieldLabel     matlab.ui.control.Label
        EndFrequency                    matlab.ui.control.NumericEditField
        EndFrequencyMHzLabel            matlab.ui.control.Label
        StartFrequency                  matlab.ui.control.NumericEditField
        StartFrequencyMHzLabel          matlab.ui.control.Label
        MeasurementTypeDropDown         matlab.ui.control.DropDown
        CalibrationTab                  matlab.ui.container.Tab
        PACalGridLayout                 matlab.ui.container.GridLayout
        FixedCalibrationLabel           matlab.ui.control.Label
        DriverResponseLabel             matlab.ui.control.Label
        SmallSignalFrequencyResponseLabel  matlab.ui.control.Label
        OutputAttenuationdBEditFieldLabel  matlab.ui.control.Label
        LargeSignalDriverResponseFilenameField  matlab.ui.control.EditField
        LoadLargeSignalDriverResponseButton  matlab.ui.control.Button
        OutputSmallSignalFilenameField  matlab.ui.control.EditField
        InputAttenuationdBLabel         matlab.ui.control.Label
        CalibrationModeLabel            matlab.ui.control.Label
        LoadOuputSmallSignalButton      matlab.ui.control.Button
        InputSmallSignalFilenameField   matlab.ui.control.EditField
        LoadInputSmallSignalButton      matlab.ui.control.Button
        OutputAttenuationValueField     matlab.ui.control.NumericEditField
        InputAttenuationValueField      matlab.ui.control.NumericEditField
        CalibrationModeDropDown         matlab.ui.control.DropDown
        PATestSettingsPanel             matlab.ui.container.Panel
        PATestSettingsGridLayout        matlab.ui.container.GridLayout
        FrequencySingleDropDown         matlab.ui.control.DropDown
        FrequencySingleDropDownLabel    matlab.ui.control.Label
        ChannelVoltageDropDown          matlab.ui.control.DropDown
        ChannelVoltageDropDownLabel     matlab.ui.control.Label
        PSUChannelDropDown              matlab.ui.control.DropDown
        PSUChannelDropDownLabel         matlab.ui.control.Label
        PlotPAData                      matlab.ui.control.Button
        LoadPAData                      matlab.ui.control.Button
        StartPAMeasurement              matlab.ui.control.Button
        PAResultsTabs                   matlab.ui.container.TabGroup
        SingleTab                       matlab.ui.container.Tab
        PAPlotSingleGridLayout          matlab.ui.container.GridLayout
        SingleFrequencyPAPlot           matlab.ui.control.UIAxes
        SweepTab                        matlab.ui.container.Tab
        PAPlotSweepGridLayout           matlab.ui.container.GridLayout
        CompressionPointsPlot           matlab.ui.control.UIAxes
        PeakDEPAEPlot                   matlab.ui.control.UIAxes
        PeakGainPlot                    matlab.ui.control.UIAxes
        GainvsOutputPowerPlot           matlab.ui.control.UIAxes
        AntennaTab                      matlab.ui.container.Tab
        AntGridLayout                   matlab.ui.container.GridLayout
        TestandPlotSettingsPanel        matlab.ui.container.Panel
        AntTestSettingsGridLayout       matlab.ui.container.GridLayout
        PlotPhiDropDown                 matlab.ui.control.DropDown
        PlotPhiDropDownLabel            matlab.ui.control.Label
        StopTestButton                  matlab.ui.control.Button
        LoadResultsButton               matlab.ui.control.Button
        PlotResultsButton               matlab.ui.control.Button
        StartTestButton                 matlab.ui.control.Button
        PlotThetaDropDown               matlab.ui.control.DropDown
        PlottingAngleLabel              matlab.ui.control.Label
        PlotFrequencyMHzDropDown        matlab.ui.control.DropDown
        PlotFrequencyMHzDropDownLabel   matlab.ui.control.Label
        AntennaSettings                 matlab.ui.container.TabGroup
        AntInstrumentsTab               matlab.ui.container.Tab
        AntInstrumentGridLayout         matlab.ui.container.GridLayout
        MeasurementDelaysLabel          matlab.ui.control.Label
        AntennaDisconnectButton         matlab.ui.control.Button
        AntennaConnectButton            matlab.ui.control.Button
        AntennaMeasurementDelayValueField  matlab.ui.control.NumericEditField
        LinearSliderStatusLabel         matlab.ui.control.Label
        LinearSliderLabel               matlab.ui.control.Label
        LinearSliderDropDown            matlab.ui.control.DropDown
        EMCenterStatusLabel             matlab.ui.control.Label
        EMCenterLabel                   matlab.ui.control.Label
        EMCenterDropDown                matlab.ui.control.DropDown
        VNADropDown                     matlab.ui.control.DropDown
        VNAStatusLabel                  matlab.ui.control.Label
        VNALabel                        matlab.ui.control.Label
        VNATab                          matlab.ui.container.Tab
        AntVNAGridLayout                matlab.ui.container.GridLayout
        SweepPointsLabel                matlab.ui.control.Label
        MeasurementDropDown_2Label      matlab.ui.control.Label
        VNAEndFrequency                 matlab.ui.control.NumericEditField
        EndFrequencyMHzLabel_6          matlab.ui.control.Label
        VNAStartFrequency               matlab.ui.control.NumericEditField
        VNASweepPoints                  matlab.ui.control.NumericEditField
        VNAMeasurement                  matlab.ui.control.DropDown
        StartFrequectSpanMHzLabel_15    matlab.ui.control.Label
        Table                           matlab.ui.container.Tab
        AntTableGridLayout              matlab.ui.container.GridLayout
        TableSpeedSliderLabel_2         matlab.ui.control.Label
        ThetaSingleSweepSwitch          matlab.ui.control.Switch
        TableSpeedSlider                matlab.ui.control.Slider
        TableSpeedSliderLabel           matlab.ui.control.Label
        TableEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_13    matlab.ui.control.Label
        TableStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_12    matlab.ui.control.Label
        TableStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_11    matlab.ui.control.Label
        MovetoAngleButton               matlab.ui.control.Button
        TableNewAngle                   matlab.ui.control.NumericEditField
        StopTableButton                 matlab.ui.control.Button
        TowerTab                        matlab.ui.container.Tab
        AntTowerGridLayout              matlab.ui.container.GridLayout
        TableSpeedSliderLabel_3         matlab.ui.control.Label
        PhiSingleSweepSwitch            matlab.ui.control.Switch
        TowerSpeedSlider                matlab.ui.control.Slider
        TowerSpeedSliderLabel           matlab.ui.control.Label
        TowerEndAngle                   matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_18    matlab.ui.control.Label
        TowerStepAngle                  matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_17    matlab.ui.control.Label
        TowerStartAngle                 matlab.ui.control.NumericEditField
        StartFrequectSpanMHzLabel_16    matlab.ui.control.Label
        MoveTowertoAngleButton          matlab.ui.control.Button
        TowerNewAngle                   matlab.ui.control.NumericEditField
        StopTowerButton                 matlab.ui.control.Button
        LinearSliderTab                 matlab.ui.container.Tab
        AntSliderGridLayout             matlab.ui.container.GridLayout
        StopLinearSliderButton          matlab.ui.control.Button
        SliderScan                      matlab.ui.control.Button
        SliderHoming                    matlab.ui.control.Button
        SpeedPresetDropDownLabel        matlab.ui.control.Label
        AntennaCurrentSpacing           matlab.ui.control.NumericEditField
        CurrentSpacingLabel             matlab.ui.control.Label
        LinearSliderNewPosition         matlab.ui.control.NumericEditField
        MovetoPositionButton            matlab.ui.control.Button
        LinearSliderCurrentPosition     matlab.ui.control.NumericEditField
        SliderPositioncmLabel           matlab.ui.control.Label
        SpeedPresetDropDown             matlab.ui.control.DropDown
        AntennaPhysicalSize             matlab.ui.control.NumericEditField
        AntennaOffsetLabel              matlab.ui.control.Label
        AntennaPattern                  matlab.ui.container.TabGroup
        AntennaGainTab                  matlab.ui.container.Tab
        AntGainGridLayout               matlab.ui.container.GridLayout
        BrowseReferenceFileButton       matlab.ui.control.Button
        ReferenceGainFileField          matlab.ui.control.EditField
        PlotReferenceGainButton         matlab.ui.control.Button
        GainvsFrequencyBoresightGain    matlab.ui.control.UIAxes
        ReturnLossBoresightGain         matlab.ui.control.UIAxes
        RadiationPatternTab             matlab.ui.container.Tab
        AntPatternGridLayout            matlab.ui.container.GridLayout
        GainvsAngle2D                   matlab.ui.control.UIAxes
        GainvsFrequency2D               matlab.ui.control.UIAxes
        ReturnLoss2D                    matlab.ui.control.UIAxes
        RadiationPattern3DTab           matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
    end


    properties (Access = private)
        %% App Specific Settings
        StoredAddresses = {};      % Cell array containing the IP adresses of the lab instruments.
        LINEAR_SLIDER_RANGE = 2    % Maximum linear slider movement range (2 meters)
        offsetSpacing = 0.8062;    % Spacing between antennas (m)
        linearSpacing              % Spacing added by linear slider (m)
    end

    properties (Access = public)
        %% Instrument Objects (For VISA SCPI commands)
        PowerSupplyA               % DC Power Supply A 
        PowerSupplyB               % DC Power Supply B
        SignalGenerator            % Signal Generator
        SpectrumAnalyzer           % Spectrum Analyzer
        VNA                        % Vector Network Analyzer
        EMCenter                   % Anechoic Chamber Control  
        LinearSlider               % Anechoic Chamber Linear Slider

        %% Power Amplifier Properties
        ChannelToDeviceMap         % A containers.Map linking logical channel names (e.g., 'A1') to PSU device channels (e.g., 'CH1,PSUA').
        PSUMode                    % Single, Dual, or Quad Supply Mode
        TypeFreqMeasurementMode    % Single frequency or sweep frequencies
        ChannelNames               % Struct containing the names of the channels to be used by the PSU.
        FilledPSUChannels          % Cell array containing the names of the configured PSU channels that are ready for use.
        DrainChannels              % Cell array containing the channels designated as drains.
        GateChannels               % Cell array containing the channels designated as gates.
        InputSpFile                % Path for the S2P file for input network de-embedding.
        OutputSpFile               % Path for the S2P file for output network de-embedding.
        DriverFile                 % Path for the driver configuration file containing a power amplifier characterization.
        PAMeasurementsTable        % Table storing the results of various measurements for the power amplifier.

        % Measurement Properties
        PA_DataTable
        PA_PSU_Channels
        PA_PSU_Voltages
        PA_PSU_SelectedVoltages

        %% Antenna Properties
        AntennaStopRequested = false; % Boolean flag: true if user stopped the antenna test, false otherwise.
        ReferenceGainFile             % Reference antenna boresight data
        Antenna_Data                  % Antenna results data table
        setupSpacing                  % Total spacing between antennas accounting for LS and offset (m)
        RadiationPlot2D               %
    end

    methods (Access = public)
        function PSUChangeModeHandle(app)
            % Public handle function to execute callback.
            app.PSUChangeMode();
        end
        function PAPlotMeasurementHandle(app)
            % Public handle function to execute callback.
            app.PAPlotMeasurement();
        end

        function displayError(app, ME)
            % This function displays a detailed error message to the GUI 
            % alert box. It takes in an MException object (ME), extracts
            % the error details (message, type, and stack trace), 
            % and formats them into a readable string for the user.
            detailedErrorMessage = sprintf('Error: %s\n\nType of Error: %s\n\nStack Trace:\n', ME.message, ME.identifier);

            for k = 1:length(ME.stack)
                detailedErrorMessage = sprintf('%sIn file: %s\nFunction: %s\nLine: %d\n\n', ...
                    detailedErrorMessage, ME.stack(k).file, ME.stack(k).name, ME.stack(k).line);
            end

            uialert(app.UIFigure, detailedErrorMessage, 'Error', 'Icon', 'warning');
        end
        
        function updateAntennaPlotDropdowns(app)
            if ~isempty(app.Antenna_Data)
                % Get unique values from the data to fill in the dropdown
                % menus.
                uniqueFreqs = unique(app.Antenna_Data.FrequencyMHz);
                uniqueTheta = unique(app.Antenna_Data.Thetadeg);
                uniquePhi = unique(app.Antenna_Data.Phideg);
                
                % Convert to cell array of strings.
                freqStrings = cellstr(num2str(uniqueFreqs));
                thetaStrings = cellstr(num2str(uniqueTheta));
                phiStrings = cellstr(num2str(uniquePhi));
                
                % Update the dropdown items and their values.
                app.PlotFrequencyMHzDropDown.Items = freqStrings;
                app.PlotFrequencyMHzDropDown.Value = freqStrings{1};
                app.PlotThetaDropDown.Items = thetaStrings;
                app.PlotThetaDropDown.Value = thetaStrings{1};
                app.PlotPhiDropDown.Items = phiStrings;
                app.PlotPhiDropDown.Value = phiStrings{1};
            end
        end
    end

    methods (Access = private)
        function resetAntennaMeasurementProperties(app)
            app.Theta = [];
            app.Antenna_Frequencies = [];
            app.S11_Mag_dB = [];
            app.S21_Mag_dB = [];
            app.S22_Mag_dB = [];
            app.AntennaGain_dBi = [];
        end

        function connectInstruments(app, instrumentNames, instrumentConnections)
            % This function establishes a connection to a set of instruments using their VISA resource names. It iterates through 
            % the provided instrument names and resources, checks if the instrument is enabled for connection, and attempts to connect 
            % using MATLAB’s visadev function. Upon successful connection, the function updates the UI with the connection status and 
            % device information; if the connection fails, it displays an error message.
            for i = 1:numel(instrumentNames)
                instrumentName = instrumentNames{i};
                instrumentResource = instrumentConnections{i};
                dropdownFieldName = strcat(instrumentName, 'DropDown');
                statusLabelField = strcat(instrumentName, 'StatusLabel'); 

                if strcmp(app.(dropdownFieldName).Enable, 'on')
                    if ~isempty(instrumentResource) && ~strcmp(instrumentResource, 'NA')
                        try
                            app.(instrumentName) = visadev(instrumentResource);
                            app.(instrumentName).ByteOrder = 'little-endian';
                            app.(dropdownFieldName).Enable = 'off';
                            idn = writeread(app.(instrumentName), '*IDN?');

                            % Update status label to show success.
                            if strcmp(instrumentName, 'EMCenter')
                                idnParts = split(idn, ' ');
                                app.(statusLabelField).Text = sprintf("Connected to %s", idnParts{3});
                            else
                                idnParts = split(idn, ",");  
                                app.(statusLabelField).Text = sprintf("Connected to %s", idnParts{2});
                            end
                        catch ME
                            % Update status label to show failure.
                            app.(statusLabelField).Text = "Failed";
                            if strcmp(ME.identifier,'instrument:interface:visa:unableToDetermineInterfaceType')
                                app.(statusLabelField).Text = "Instrument Unavailable";
                                uialert(app.UIFigure, 'Failed to connect. Check the network settings and selected addresses.', 'Error', 'Icon', 'warning');
                            else
                                app.displayError(ME);
                            end
                        end
                    else
                        % Update status label if "None: NA" is selected.
                        app.(statusLabelField).Text = "Not Connected";
                    end
                end
            end
        end

        function disconnectInstruments(app, instrumentNames, instrumentConnections)
            % This function disconnects a set of instruments. It follows the reverse logic of connectInstrument.
            % For each instrument, it checks if it exists, disconnects it, updates the UI status, and re-enables
            % the dropdown for future connections.

             for i = 1:numel(instrumentConnections)
                 instrumentName = instrumentNames{i};
                 dropdownFieldName = strcat(instrumentName, 'DropDown');
                 statusLabelField = strcat(instrumentName, 'StatusLabel');
                 
                 if ~isempty(app.(instrumentName))
                     try
                        % Flush the buffer before closing if applicable.
                        if ismethod(app.(instrumentName), 'flush')
                            flush(app.(instrumentName));
                        end
                          
                        % Clear the instrument app reference.
                        app.(instrumentName) = [];

                        % Now we re-enable the dropdowns for future
                        % connections.
                        app.(dropdownFieldName).Enable = 'on';

                        % And update the status label to let the user know
                        % the instrument was disconnected.
                        app.(statusLabelField).Text = "Disconnected";
                     catch ME
                         app.displayError(ME);
                     end
                 else
                     % Ensure dropdown is enabled even if an instrument was
                     % already disconnected.
                     app.(dropdownFieldName).Enable = 'on';
                     app.(statusLabelField).Text = "Not Connected";
                 end
             end                     
        end

        function saveInstrumentAddressestoTextFile(~, address, model)
            % Save to the text file containing the lab instruments and 
            % their respective IP addresses.
            fid = fopen('instrument_address_history.txt', 'a');
            
            % If the user provided the instrument model, we save it along 
            % with the IP address.
            if nargin > 2 && ~isempty(model)
                fprintf(fid, '%% Model: %s\n', model);
            end
            
            % Save the IP address.
            fprintf(fid, '%% Address: %s\n', address);
            fclose(fid);
        end

        function loadInstrumentAddressestoTextFile(app)
            [appPath, ~, ~] = fileparts(mfilename('fullpath'));
            filename = fullfile(appPath, 'instrument_address_history.txt');

            % Load from the text file containing the lab instruments and 
            % their respective IP addresses.
            if isfile(filename)
                fileContent = fileread(filename);
                lines = strsplit(fileContent, '\n');
 
                addresses = {};
                displayNames = {};
                currentModel = '';
                
                for i = 1:length(lines)
                    line = strtrim(lines{i});
                    
                    if isempty(line)
                        continue;
                    end
                    
                    % Check if this is the line containing the instrument 
                    % model.
                    if startsWith(line, '% Model:')
                        currentModel = strtrim(extractAfter(line, '% Model:'));
                    % Check if this is the line containing the instrument
                    % address.
                    elseif startsWith(line, '% Address:')
                        address = strtrim(extractAfter(line, '% Address:'));
                        addresses{end+1} = address;
                        
                        % Create display name with model and address. 
                        % Model: SMW200A TCPIP0::192.168.3.52::hislip0::INSTR
                        if ~isempty(currentModel)
                            displayNames{end+1} = sprintf('%s: %s', currentModel, address);
                        else
                            displayNames{end+1} = address;
                        end
                        
                        currentModel = '';
                    end
                end

                % Update the app's instrument dropdowns.
                Dropdowns = {app.PowerSupplyADropDown, app.PowerSupplyBDropDown, app.SpectrumAnalyzerDropDown, app.SignalGeneratorDropDown, app.VNADropDown, app.EMCenterDropDown, app.LinearSliderDropDown};
                for idx = 1:numel(Dropdowns)
                    currentDropdown = Dropdowns{idx};
                    currentDropdown.Items = displayNames;
                end
            else
                app.StoredAddresses = {};
            end
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Handle Application startup settings.
            app.UIFigure.WindowState = 'maximized';
            drawnow;
            app.loadInstrumentAddressestoTextFile();
            
            % Add all relevant Application support functions to the path.
            addpath(genpath('.\'))
            
            % Handle PA specific settings.
            app.PSUMode = 'Single Supply';
            PAChangeCalibrationMode(app);
            PAChangeFrequencyMeasurementMode(app);
            app.ChannelNames = struct();
            app.ChannelToDeviceMap = containers.Map({'A1', 'A2', 'B1', 'B2'}, {'CH1,PSUA', 'CH2,PSUA', 'CH1,PSUB', 'CH2,PSUB'});

            % Handle Antenna specific settings.
            app.RadiationPlot2D = polaraxes(app.AntPatternGridLayout);
            app.RadiationPlot2D.Units = "pixels";
            app.RadiationPlot2D.Layout.Row = 2;
            app.RadiationPlot2D.Layout.Column = 2;  
            app.RadiationPlot2D.Title.String = '2D Radiation Pattern';
            app.AntennaCurrentSpacing.Enable = 'off';
            app.LinearSliderCurrentPosition.Enable = 'off';
            app.SpeedPresetDropDown.Enable = 'off';
            app.MovetoPositionButton.Enable = 'off';
            app.LinearSliderNewPosition.Enable = 'off';
        end

        % Value changed function: PowerSupplyADropDown, 
        % ...and 3 other components
        function PAInstrumentsAddressChanged(app, event)
            % When a new address is entered into the instrument address 
            % dropdown menu, this callback:
            % 1. Adds the address to the stored addresses list.
            % 2. Updates all instrument menus with the new address.
            % 3. Saves the updated address list to a text file outside the application.
            % 4. If an address is chosen from the menu (already valid
            % address) the function will return.
      
            Input = event.Source.Value;
            pattern = '^([A-Za-z0-9\-]+):\s*((TCPIP0::[\d\.]+::[\w\-]+::[\w\-]+)|(GPIB\d+::\d+::INSTR))$';
    
            % Validation check for addresses loaded into the app. 
            if ~isempty(regexp(Input, pattern, 'once')) || strcmp(Input, 'None: NA')
                return;
            end

            try        
                % Validate that the input starts with "Model:".
                if ~startsWith(Input, 'Model:', 'IgnoreCase', true)
                    error('Invalid format. The input must start with "Model:"');
                end
                
                pattern = '^Model:\s+(\S+)\s+(.*)';
                tokens = regexp(Input, pattern, 'tokens');
                if isempty(tokens)
                    error('Invalid input format. Ensure the format is "Model: <model> <address>"');
                end
                
                % Extract the model and the address from regex tokens.
                model = tokens{1}{1};
                address = strtrim(tokens{1}{2});
                
                % Check if model and address are valid.
                if isempty(model)
                    error('No model specified after "Model:".');
                end
                if isempty(address)
                    error('No address specified after the model name.');
                end
                
                if ~ismember(address, app.StoredAddresses)
                    app.StoredAddresses{end+1} = address;
                    
                    % Update the app's instrument dropdowns.
                    Dropdowns = {app.PowerSupplyADropDown, app.PowerSupplyBDropDown, app.SpectrumAnalyzerDropDown, app.SignalGeneratorDropDown, app.VNADropDown, app.EMCenterDropDown, app.LinearSliderDropDown};
                    for idx = 1:numel(Dropdowns)
                        currentDropdown = Dropdowns{idx};
                        if ~ismember(address, currentDropdown.Items)
                            currentDropdown.Items{end+1} = address;
                        end
                    end
                    
                    % Save the new address and model to the text file.
                    saveInstrumentAddressestoTextFile(app, address, model);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PAConnectButton
        function PAConnectInstruments(app, event)
            % Code waits for user to select an address in the dropdown menu
            % then it gets that resource and attempts to connect to it.
            instrumentNames = {'PowerSupplyA', 'PowerSupplyB', 'SignalGenerator', 'SpectrumAnalyzer'};
            instrumentResources = {app.PowerSupplyADropDown.Value,app.PowerSupplyBDropDown.Value, app.SignalGeneratorDropDown.Value, app.SpectrumAnalyzerDropDown.Value};
            
            pattern = '(TCP|USB|GPIB)[^ ]*';

            for i = 1:length(instrumentResources)
                instrumentResources{i} = regexp(instrumentResources{i}, pattern, 'match', 'once');
            end
            
            app.connectInstruments(instrumentNames, instrumentResources);
        end

        % Button pushed function: PADisconnectButton
        function PADisconnectInstruments(app, event)
            instrumentNames = {'PowerSupplyA', 'PowerSupplyB', 'SignalGenerator', 'SpectrumAnalyzer'};
            instrumentConnections = {app.PowerSupplyA, app.PowerSupplyB, app.SignalGenerator, app.SpectrumAnalyzer};
            app.disconnectInstruments(instrumentNames, instrumentConnections)
        end

        % Value changed function: PowerSupplyModeDropDown
        function PSUChangeMode(app, event)
            newMode = app.PowerSupplyModeDropDown.Value; 

            % The default value of app.PSUMode is 'Single Supply' for
            % safety reasons.
            if isempty(newMode)
                app.PSUMode = 'Single Supply';
            else
                app.PSUMode = newMode;
            end

            % If the mode changes to 'No Supply' all input parameters to
            % the PSU are disabled to the user.
            switch newMode
                case 'No Supply'
                    app.SupplyCurrent.Enable = 'off';
                    app.StartVoltage.Enable = 'off';
                    app.StopVoltage.Enable = 'off';
                    app.StepVoltage.Enable = 'off';
                otherwise
                    app.SupplyCurrent.Enable = 'on';
                    app.StartVoltage.Enable = 'on';
                    app.StopVoltage.Enable = 'on';
                    app.StepVoltage.Enable = 'on';
            end

            app.PSUChannelDropDownOpening();
        end

        % Value changed function: PowerSupplyChannelDropDown
        function PSUChangeChannel(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel) || ~ischar(selectedChannel)
                return;
            end

            % If the channel is new, initialize its values
            if ~isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel) = struct('Current', 0, 'Start', 0, 'Stop', 0, 'Step', 0, 'Mode', 'Single');

                % New channel is drain by default
                if ~ismember(selectedChannel, [app.DrainChannels, app.GateChannels])
                    app.DrainChannels{end+1} = selectedChannel;
                    app.DrainGateSwitch.Value = 'Drain';
                end
            else
                % Set the mode switch based on saved channel mode
                if isfield(app.ChannelNames.(selectedChannel), 'Mode')
                    app.SingleSweepSwitch.Value = app.ChannelNames.(selectedChannel).Mode;
                else
                    app.SingleSweepSwitch.Value = 'Single';  % Default to single if mode not set
                    app.ChannelNames.(selectedChannel).Mode = 'Single';
                end

                % Update DrainGateSwitch based on channel designation
                if any(strcmp(selectedChannel, app.DrainChannels))
                    app.DrainGateSwitch.Value = 'Drain';
                elseif any(strcmp(selectedChannel, app.GateChannels))
                    app.DrainGateSwitch.Value = 'Gate';
                else
                    % Default to Drain if not in either list
                    app.DrainGateSwitch.Value = 'Drain';
                    app.DrainChannels{end+1} = selectedChannel;
                end

                % Call the SingleSweepSwitch function to update UI visibility
                PSUChangeSingleSweepVoltages(app, []);
            end

            % Load saved values into UI edit fields
            app.SupplyCurrent.Value = app.ChannelNames.(selectedChannel).Current;
            app.StartVoltage.Value = app.ChannelNames.(selectedChannel).Start;
            app.StopVoltage.Value = app.ChannelNames.(selectedChannel).Stop;
            app.StepVoltage.Value = app.ChannelNames.(selectedChannel).Step;
        end

        % Drop down opening function: PowerSupplyChannelDropDown
        function PSUChannelDropDownOpening(app, event)
            % Check which PSUs are connected
            hasPSUA = ~isempty(app.PowerSupplyA);
            hasPSUB = ~isempty(app.PowerSupplyB);

            if ~hasPSUA && ~hasPSUB
                items = {''};
                app.PowerSupplyChannelDropDown.Items = items;
                return;
            end

            items = {};
            if hasPSUA
                items = [items {'A1', 'A2'}];
            end
            if hasPSUB
                items = [items {'B1', 'B2'}];
            end

            currentValue = app.PowerSupplyChannelDropDown.Value;
            
            % Populate power supply dropdown with items
            app.PowerSupplyChannelDropDown.Items = items;
            
            % Make sure there's at least one item and set to first item if current is invalid
            if ~isempty(items)
                if isempty(currentValue) || ~ismember(currentValue, items)
                    app.PowerSupplyChannelDropDown.Value = items{1};
                else
                    app.PowerSupplyChannelDropDown.Value = currentValue;
                end
                % Change the channel if there is a valid selection
                if ~isempty(app.PowerSupplyChannelDropDown.Value)
                    PSUChangeChannel(app, []);
                end
            end
        end

        % Value changed function: SupplyCurrent
        function PSUSetLimitingCurrent(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Current = app.SupplyCurrent.Value;
            end 
            populatePSUChannels(app);        
        end

        % Value changed function: StartVoltage
        function PSUSetStartVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Start = app.StartVoltage.Value;
            end   
            populatePSUChannels(app); 
            
        end

        % Value changed function: StopVoltage
        function PSUSetStopVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Stop = app.StopVoltage.Value;
            end 
            populatePSUChannels(app); 
            
        end

        % Value changed function: StepVoltage
        function PSUSetStepVoltage(app, event)
            selectedChannel = app.PowerSupplyChannelDropDown.Value;
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Step = app.StepVoltage.Value;
            end 
            populatePSUChannels(app);
        end

        % Value changed function: SingleSweepSwitch
        function PSUChangeSingleSweepVoltages(app, event)
            channelFunction = app.SingleSweepSwitch.Value;
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            % Update GUI based on single PSU value or sweep PSU values.
            if strcmp(channelFunction, 'Single')
                app.SupplyCurrent.Visible = 'on';
                app.StartVoltage.Enable = 'on';
                app.StopVoltage.Enable = 'off';
                app.StepVoltage.Enable = 'off';
            elseif strcmp(channelFunction, 'Sweep')
                app.SupplyCurrent.Visible = 'on';
                app.StartVoltage.Enable = 'on';
                app.StopVoltage.Enable = 'on';
                app.StepVoltage.Enable = 'on';
            end
    
            % Check that the selected channel is filled otherwise do not
            % store the mode selection into the channel configuration.
            if isempty(selectedChannel)
                return;
            end
            
            % Store the mode selection into the channel configuration.
            if isfield(app.ChannelNames, selectedChannel)
                app.ChannelNames.(selectedChannel).Mode = channelFunction;
            end
        end

        % Value changed function: DrainGateSwitch
        function PSUChangeDrainGate(app, event)
            channelDesignation = app.DrainGateSwitch.Value;
            selectedChannel = app.PowerSupplyChannelDropDown.Value;

            if isempty(selectedChannel)
                return;
            end

            if strcmp(channelDesignation, 'Drain')
                app.DrainChannels = unique([app.DrainChannels, {selectedChannel}]);
                app.GateChannels = setdiff(app.GateChannels, {selectedChannel});
            elseif strcmp(channelDesignation, 'Gate')
                app.GateChannels = unique([app.GateChannels, {selectedChannel}]);
                app.DrainChannels = setdiff(app.DrainChannels, {selectedChannel});
            end
        end

        % Value changed function: MeasurementTypeDropDown
        function PAChangeFrequencyMeasurementMode(app, event)
            frequencyType = app.MeasurementTypeDropDown.Value;

            % Update GUI based on single frequency or sweep frequencies 
            % value. 
            if strcmp(frequencyType, 'Single Frequency')
                app.EndFrequency.Enable = 'off';
                app.EndFrequencyMHzLabel.Enable = 'off';
                app.StepFrequency.Enable = 'off';
                app.StepFrequencyMHzEditFieldLabel.Enable = 'off';
            elseif strcmp(frequencyType, 'Sweep Frequencies')
                app.EndFrequency.Enable = 'on';
                app.EndFrequencyMHzLabel.Enable = 'on';
                app.StepFrequency.Enable = 'on';
                app.StepFrequencyMHzEditFieldLabel.Enable = 'on';
            end
        end

        % Value changed function: CalibrationModeDropDown
        function PAChangeCalibrationMode(app, event)
            CalibrationMode = app.CalibrationModeDropDown.Value;

            switch CalibrationMode
                case 'None'
                    app.InputAttenuationValueField.Enable = 'off';
                    app.OutputAttenuationValueField.Enable = 'off';
                    app.LoadInputSmallSignalButton.Enable = 'off';
                    app.LoadOuputSmallSignalButton.Enable = 'off';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';
                case 'Fixed Attenuation'
                    app.InputAttenuationValueField.Enable = 'on';
                    app.OutputAttenuationValueField.Enable = 'on';
                    app.LoadInputSmallSignalButton.Enable = 'off';
                    app.LoadOuputSmallSignalButton.Enable = 'off';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';
                case 'Small Signal'
                    app.InputAttenuationValueField.Enable = 'on';
                    app.OutputAttenuationValueField.Enable = 'on';
                    app.LoadInputSmallSignalButton.Enable = 'on';
                    app.LoadOuputSmallSignalButton.Enable = 'on';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'off';
                case 'Small + Large Signal'
                    app.InputAttenuationValueField.Enable = 'off';
                    app.OutputAttenuationValueField.Enable = 'off';
                    app.LoadInputSmallSignalButton.Enable = 'on';
                    app.LoadOuputSmallSignalButton.Enable = 'on';
                    app.LoadLargeSignalDriverResponseButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadInputSmallSignalButton
        function LoadSmallSignalInputS2PFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile('*.s2p', 'Select Input S-Parameter File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % The app expects the S2P file to be in linear magnitude
                % format so we read the file and check the file header.
                fid = fopen(filePath, 'r');
                isLinMagFormat = false;

                for i = 1:20
                    if ~feof(fid)
                        line = fgetl(fid);
                        cleanLine = regexprep(line, '\s+', '');

                        if contains(upper(cleanLine), '#HZSMAR')
                            isLinMagFormat = true;
                            break;
                        end
                    else
                        break
                    end
                end
                fclose(fid);

                % For verification purposes we call sparameters().
                testFile = sparameters(filePath);

                % If the file is in dB format, then we convert it.
                if ~isLinMagFormat
                    % Creates a new filename to let the user know this is 
                    % the linear magnitude version. 
                    [~, basename] = fileparts(file);
                    linearFileName = fullfile(path, [basename '_linear.s2p']);

                    % With rfwrite we modify the format from 'DB' to 'MA'.
                    rfwrite(testFile, linearFileName, 'FrequencyUnit', 'Hz', 'Format', 'MA');
 
                    filePath = linearFileName;
                    file = [basename '_linear.s2p'];
                end

                % Store the file path in the app as a property.
                app.InputSpFile = filePath;

                % Display the selected or converted filename in the app.
                app.InputSmallSignalFilenameField.Value = file;

            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: LoadOuputSmallSignalButton
        function LoadSmallSignalOutputS2PFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile('*.s2p', 'Select Output S-Parameter File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % The app expects the S2P file to be in linear magnitude
                % format so we read the file and check the file header.
                fid = fopen(filePath, 'r');
                isLinMagFormat = false;

                for i = 1:20
                    if ~feof(fid)
                        line = fgetl(fid);
                        cleanLine = regexprep(line, '\s+', '');

                        if contains(upper(cleanLine), '#HZSMAR')
                            isLinMagFormat = true;
                            break;
                        end
                    else
                        break
                    end
                end
                fclose(fid);

                % For verification purposes we call sparameters().
                testFile = sparameters(filePath);

                % If the file is in dB format, then we convert it.
                if ~isLinMagFormat
                    % Creates a new filename to let the user know this is 
                    % the linear magnitude version. 
                    [~, basename] = fileparts(file);
                    linearFileName = fullfile(path, [basename '_linear.s2p']);

                    % With rfwrite we modify the format from 'DB' to 'MA'.
                    rfwrite(testFile, linearFileName, 'FrequencyUnit', 'Hz', 'Format', 'MA');
 
                    filePath = linearFileName;
                    file = [basename '_linear.s2p'];
                end

                % Store the file path in the app as a property.
                app.OutputSpFile = filePath;
                % Display the selected filename in the app.
                app.OutputSmallSignalFilenameField.Value = file;
            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: LoadLargeSignalDriverResponseButton
        function LoadLargeSignalDriverResponseFile(app, event)
            % Prompt the user to select an S2P file for input.
            [file, path] = uigetfile({'*.xlsx;*.xls;*.csv', 'Data Files (*.xlsx, *.xls, *.csv)'}, 'Select Driver File');

            % Check if the user canceled the file selection.
            if isequal(file, 0)
                return;
            end

            filePath = fullfile(path, file);

            try
                % For verification purposes we call readtable().
                driverData = readtable(filePath, 'VariableNamingRule', 'preserve');

                requiredColumns = {'Frequency (MHz)', 'RF Input Power (dBm)', 'Gain'};
                for i = 1:length(requiredColumns)
                    if ~any(strcmp(driverData.Properties.VariableNames, requiredColumns{i}))
                        error('Missing required column: %s', requiredColumns{i});
                    end
                end

                app.DriverFile = filePath;

                app.LargeSignalDriverResponseFilenameField.Value = file;
            catch ME
                app.displayError(ME)
            end
        end

        % Button pushed function: StartPAMeasurement
        function PAStartMeasurement(app, event)
            % Validate the channel configuration first.
            if ~validatePSUChannels(app)
                return;
            end

            runPAMeasurement(app);
        end

        % Button pushed function: PlotPAData
        function PAPlotMeasurement(app, event)
            try
                plotPASingleMeasurement(app);
                plotPASweepMeasurement(app);
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: LoadPAData
        function PALoadData(app, event)
            try
                app.PA_DataTable = loadData(app,'PA');
            catch ME
                app.displayError(ME);
            end
        end

        % Value changed function: PSUChannelDropDown
        function PSUChannelValueChanged(app, event)
            ch = str2double(string(app.PSUChannelDropDown.Value));
            app.ChannelVoltageDropDown.Items = string(app.PA_PSU_Voltages.(sprintf('Channel%dVoltagesV', ch)));
            app.ChannelVoltageDropDown.Value = string(app.PA_PSU_SelectedVoltages(ch));
        end

        % Value changed function: FrequencySingleDropDown
        function PASingleFrequencyValueChanged(app, event)
            plotPASingleMeasurement(app);
            plotPASweepMeasurement(app);
        end

        % Value changed function: ChannelVoltageDropDown
        function PAChannelVoltageValueChanged(app, event)
            ch = str2double(string(app.PSUChannelDropDown.Value));
            app.PA_PSU_SelectedVoltages(ch) = str2double(string(app.ChannelVoltageDropDown.Value)); 
            app.PAPlotMeasurementHandle();
        end

        % Button pushed function: AntennaConnectButton
        function AntennaConnectInstruments(app, event)
            % This function handles the connection of the application to the antenna measurement instruments
            % (Vector Network Analyzer (VNA) and EMCenter). The user selects the appropriate instrument addresses via a dropdown menu, 
            % and the function uses these selections to establish connections. If successful, the instruments are ready for subsequent 
            % commands and data acquisition.      
            
            %instrumentResources = {'TCPIP0::192.168.1.113::hislip0::INSTR', 'TCPIP0::192.168.2.150::inst0::INSTR'}; 
            instrumentNames = {'VNA', 'EMCenter', 'LinearSlider'};
            instrumentResources = {app.VNADropDown.Value, app.EMCenterDropDown.Value, app.LinearSliderDropDown.Value};
            
            pattern = '(TCP|USB)[^ ]*';
            
            for i = 1:length(instrumentResources)
                instrumentResources{i} = regexp(instrumentResources{i}, pattern, 'match', 'once');
            end
            
            app.connectInstruments(instrumentNames, instrumentResources);

            if ~isempty(app.VNA)
                app.VNAStartFrequency.Value = str2double(writeread(app.VNA, ':SENSe:FREQuency:STARt?')) / 1E6;
                app.VNAEndFrequency.Value = str2double(writeread(app.VNA, ':SENSe:FREQuency:STOP?')) / 1E6;
                app.VNASweepPoints.Value = str2double(writeread(app.VNA, ':SENSe:SWEep:POINts?'));
            end

            if ~isempty(app.LinearSlider)
                % Get current position from Linear Slider.
                app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                app.AntennaCurrentSpacing.Value = app.LINEAR_SLIDER_RANGE + app.offsetSpacing - app.LinearSliderCurrentPosition.Value/100;
                app.setupSpacing = app.AntennaCurrentSpacing.Value;

                % Enable Linear Slider GUI interface
                app.AntennaCurrentSpacing.Enable = 'on';
                app.LinearSliderCurrentPosition.Enable = 'on';
                app.SpeedPresetDropDown.Enable = 'on';
                app.MovetoPositionButton.Enable = 'on';
                app.LinearSliderNewPosition.Enable = 'on';
            end
        end

        % Button pushed function: AntennaDisconnectButton
        function AntennaDisconnectInstruments(app, event)
            % Create a cell array of instrument names for reference
            instrumentNames = {'VNA', 'EMCenter', 'LinearSlider'};
            instrumentConnections = {app.VNA, app.EMCenter, app.LinearSlider};
            app.disconnectInstruments(instrumentNames, instrumentConnections)
        end

        % Value changed function: PhiSingleSweepSwitch
        function PhiChangeSingleSweepAngles(app, event)
            mode = app.PhiSingleSweepSwitch.Value;

            if strcmp(mode, 'Sweep')
                app.TowerStepAngle.Enable = "on";
                app.TowerEndAngle.Enable = "on";
            else
                app.TowerStepAngle.Enable = "off";
                app.TowerEndAngle.Enable = "off";
            end
        end

        % Value changed function: ThetaSingleSweepSwitch
        function ThetaChangeSingleSweepSwitchAngles(app, event)
            mode = app.ThetaSingleSweepSwitch.Value;

            if strcmp(mode, 'Sweep')
                app.TableStepAngle.Enable = "on";
                app.TableEndAngle.Enable = "on";
            else
                app.TableStepAngle.Enable = "off";
                app.TableEndAngle.Enable = "off";
            end

        end

        % Value changed function: SpeedPresetDropDown
        function LinearSliderChangeSpeed(app, event)
            % This function updates the movement speed of the linear slider
            % by sending a speed preset command to the hardware. The speed 
            % settings are predefined as presets, with values ranging from
            % 1 (slowest) to 8 (fastest). The selected speed preset is read 
            % from the application's dropdown menu and applied directly to
            % the slider hardware.
            
            try 
                % Send command to linear slider to change its speed.
                speedPreset = str2double(app.SpeedPresetDropDown.Value);
                writeline(app.LinearSlider, sprintf('AXIS1:S%d', speedPreset));
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoPositionButton
        function LinearSliderChangePosition(app, event)
            % This function updates the position the linear slider and 
            % dynamically adjusts associated parameters based on the 
            % slider's movement. It continuously monitors the slider's 
            % status to ensure the desired position is reached and updates
            % the application's calculated spacing values in real-time.
            
            try
                % Send command to linear slider to move to the specified
                % position.
                writeline(app.LinearSlider, sprintf('AXIS1:SK %d', app.LinearSliderNewPosition.Value));
                motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                
                % Continuously update the setup spacing until motion is
                % complete.
                while strcmp(motion, '+1') || strcmp(motion, '-1')
                    motion = writeread(app.LinearSlider, 'AXIS1:DIR?');
                    app.LinearSliderCurrentPosition.Value = str2double(writeread(app.LinearSlider, 'AXIS1:CP?'));
                    
                    sliderPosition = app.LinearSliderCurrentPosition.Value / 100; % Convert to meters.
                    baseSpacing = app.LINEAR_SLIDER_RANGE - sliderPosition;
                    totalSpacing = baseSpacing + app.offsetSpacing;
                    
                    % Subtract antenna physical size if provided.
                    if ~isempty(app.AntennaPhysicalSize.Value)
                        totalSpacing = totalSpacing - app.AntennaPhysicalSize.Value / 100; % Convert to meters.
                    end
                    
                    % Update the displayed spacing in the GUI.
                    app.AntennaCurrentSpacing.Value = totalSpacing;
                    
                    % Small delay to prevent overwhelming the system.
                    pause(0.1);
                end
        
                app.setupSpacing = app.AntennaCurrentSpacing.Value;
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: SliderScan
        function LinearSliderScanMovement(app, event)
            % This function initiates the scan mode of the linear slider, 
            % causing it to move continuously between the soft limit 
            % positions.

            try
                if isempty(app.LinearSlider)
                    return;
                else
                    writeread(app.LinearSlider, 'AXIS1:SCAN')
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: SliderHoming
        function LinearSliderHomingMovement(app, event)
            % This function performs a homing operation on the linear 
            % slider to calibrate its zero position. It checks whether the 
            % slider has already been homed and, if not, initiates a homing
            % sequence by directing the slider to its home sensor for
            % proper positional alignment.

            try
                if isempty(app.LinearSlider)
                    return;
                else
                    % Query if axis has been homed.
                    if writeread(app.LinearSlider, 'AXIS1:ZERO?') == 0 
                        % Seek slider home sensor.
                        writeread(app.LinearSlider, 'AXIS1:HOME') 
                    end
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopLinearSliderButton
        function LinearSliderStopMovement(app, event)
            % This function sends a stop command to halt the movement of 
            % the linear slider. It is triggered when the user presses the 
            % stop button in the linear slider settings menu. 

            try
                if isempty(app.LinearSlider)
                    return;
                else
                    writeline(app.LinearSlider, 'AXIS1:STOP');
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Value changed function: AntennaPhysicalSize
        function LinearSliderApplyAntennaPhysicalOffset(app, event)
            % This function recalculates and updates the antenna spacing 
            % based on the entered physical size of the antenna. It factors
            % in the current position of the linear slider and an offset 
            % value, subtracting the antenna's physical size to ensure 
            % accurate spacing for measurement or positioning purposes.

            AntennaPhysicalSizeOffset = app.AntennaPhysicalSize.Value;
    
            % Recalculate the current spacing.
            sliderPosition = app.LinearSliderCurrentPosition.Value / 100; % Convert to meters.
            baseSpacing = app.LINEAR_SLIDER_RANGE - sliderPosition;
            totalSpacing = baseSpacing + app.offsetSpacing;
            
            % Subtract antenna physical size offset.
            if ~isempty(AntennaPhysicalSizeOffset)
                totalSpacing = totalSpacing - AntennaPhysicalSizeOffset / 100; % Convert to meters.
            end
            
            % Update the displayed spacing.
            app.AntennaCurrentSpacing.Value = totalSpacing;
            
            % Update the setup spacing.
            app.setupSpacing = totalSpacing;
        end

        % Value changed function: TableSpeedSlider
        function TableChangeSpeed(app, event)
           % This function updates the turn-table speed value in the 
           % application when the user adjusts the TableSpeedSlider. While 
           % this function does not directly update the speed of the
           % hardware, it ensures that the application's internal settings 
           % reflect the new speed. The updated value will be used in 
           % subsequent operations involving the turntable, allowing the 
           % user to adjust the speed without immediate hardware changes.
 
            try
                app.TableSpeedSlider.Value = app.TableSpeedSlider.Value; 
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MovetoAngleButton
        function TableChangeAngle(app, event)
            % This function initiates the process of changing the 
            % turn-table angle to a new value. The function sends a command 
            % to set the turn-table's speed and a command to adjust the 
            % turn-table's angle to the user requested value. It 
            % continuously checks if the StopRequested flag is set to true
            % and halts the operation if so.  
            
            try
                app.AntennaStopRequested = false;
                writeline(app.EMCenter, sprintf('1A:SPEED %d', app.TableSpeedSlider.Value));
                writeline(app.EMCenter, sprintf('1A:SK %d', app.TableNewAngle.Value));
                status = writeread(app.EMCenter,"1A:*OPC?");
                status = str2double(strtrim(status));

                while status ~= 1
                    drawnow;

                    if app.AntennaStopRequested
                        break;
                    end

                    status = writeread(app.EMCenter,"1A:*OPC?");
                    status = str2double(strtrim(status));
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTableButton
        function TableStopMovement(app, event)
            % This function is executed when the user requests to stop the turn-table movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the table to halt any ongoing operation. 
            
            app.AntennaStopRequested = true;
            
            try
                writeline(app.EMCenter, '1A:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Value changed function: TowerSpeedSlider
        function TowerChangeSpeed(app, event)
           % This function updates the tower speed value in the application when the user adjusts the TowerSpeedSlider. While this 
           % function does not directly update the speed of the hardware, it ensures that the application's internal settings reflect 
           % the new speed. The updated value will be used in subsequent operations involving the turntable, allowing the user to adjust the speed without immediate hardware changes.
 
            try
                app.TowerSpeedSlider.Value = app.TowerSpeedSlider.Value; 
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: MoveTowertoAngleButton
        function TowerChangeAngle(app, event)
            % This function initiates the process of changing the tower's angle to a new value. The function sends a command 
            % to set the tower's speed to a default value of 20 and a command to adjust the tower's angle to the user requested value. 
            % It continuously checks if the StopRequested flag is set to true and halts the operation if so. 
            
            try
                app.AntennaStopRequested = false;
                writeline(app.EMCenter, '1B:SPEED 20');
                writeline(app.EMCenter, sprintf('1B:SK %d', app.TowerNewAngle.Value));
            
                while true                    
                    drawnow;
                    
                    if app.AntennaStopRequested
                        break;
                    end

                    pause(0.1);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTowerButton
        function TowerStopMovement(app, event)
            % This function is executed when the user requests to stop the tower movement operation. It sets the 
            % StopRequested flag to true and sends a stop command to the tower to halt any ongoing operation. 
            
            app.AntennaStopRequested = true;

            try
                writeline(app.EMCenter, '1B:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StartTestButton
        function AntennaStartMeasurement(app, event)
            % This function validates the user inputs for the VNA 
            % measurement settings and performs the selected measurement
            % type ("Gain Characterization" or "Pattern Measurement"). It
            % ensures that the start and end frequencies are set and 
            % distinct, the number of sweep points is specified, and the
            % antenna physical size is provided. Based on the chosen
            % test type, the function either calls the measureBoresightGain
            % function for gain characterization or the get2DAntennaGain 
            % function for 2D radiation pattern measurement.

            % Validate the antenna setup configuration first.
            if ~validateAntennaMeasurement(app)
                return;
            end  

            try
                switch app.VNAMeasurement.Value
                    case 'Gain Characterization'
                        measureBoresightGain(app);
                    case 'Pattern Measurement'
                        get2DAntennaGain(app);
                end
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: StopTestButton
        function AntennaStopMeasurement(app, event)
            % This function handles the user's request to stop the ongoing 
            % 2D radiation pattern measurement. When the stop request is 
            % triggered, the function terminates the measurement process,
            % checks if any data has been recorded, and prompts the user to
            % decide whether to save the collected data or not. If the user
            % opts to save, the function prepares the data and saves it. 

            try
                % Set the antenna stop measurement flag to true.
                app.AntennaStopRequested = true;
                % Stop both positioners again for extra safety.
                writeline(app.EMCenter, '1A:ST');
                writeline(app.EMCenter, '1B:ST');
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotResultsButton
        function AntennaPlotMeasurement(app, event)
            % This function is responsible for plotting the 2D antenna gain
            % measurement results, providing the user with visual insights 
            % into the measurement data. 

            plotAntenna2DRadiationPattern(app);
        end

        % Button pushed function: LoadResultsButton
        function AntennaLoadData(app, event)
            % This function is responsible for loading an antenna gain file
            % into the application. The file must adhere to the predefined
            % column headers for antenna gain data, which include:
            % 
            %       - Theta (deg)
            %       - Phi (deg)
            %       - Frequency (MHz)
            %       - Gain (dBi)
            %       - Return Loss (dB)
            %       - Return Loss (deg)
            % 
            % Upon successful loading, the function extracts the data and 
            % populates the relevant fields within the application, such as
            % dropdown menus for selecting specific angles (Theta, Phi) and
            % frequencies for plotting. The function also ensures that the
            % dropdown menus are populated with unique values. After 
            % populating the dropdowns, an automatic replotting of the 
            % antenna's radiation pattern is done.

            try 
                % Load the antenna gain file
                loadData(app,'Antenna');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(fieldnames(app.Antenna_Data))
                    return;
                end
                
                % Populate dropdown menus, with the angles and frequencies
                % so user can select a specific values for plotting.
                app.PlotThetaDropDown.Items = string(unique(app.Antenna_Data.Thetadeg));
                app.PlotPhiDropDown.Items = string(unique(app.Antenna_Data.Phideg));
                app.PlotFrequencyMHzDropDown.Items = string(unique(app.Antenna_Data.FrequencyMHz));

                % Set default values for the angle and frequency.
                app.PlotThetaDropDown.Value = app.PlotThetaDropDown.Items(1);
                app.PlotPhiDropDown.Value = app.PlotPhiDropDown.Items(1);
                app.PlotFrequencyMHzDropDown.Value = app.PlotFrequencyMHzDropDown.Items(1);

                % Replot automatically
                plotAntenna2DRadiationPattern(app);
            catch ME    
                app.displayError(ME);
            end
        end

        % Value changed function: PlotPhiDropDown, PlotThetaDropDown
        function AntennaSelectedAngleValue(app, event)
            % This function automatically plots the antenna plots based on 
            % the user's selected angles  from the theta or phi) dropdown 
            % menus.
        
            % Replot automatically
            plotAntenna2DRadiationPattern(app);
        end

        % Value changed function: PlotFrequencyMHzDropDown
        function AntennaSelectedFrequencyValue(app, event)
            % This function automatically plots the antenna plots based on 
            % the user's selected frequency from the frequency dropdown 
            % menus.

            % Replot automatically.
            plotAntenna2DRadiationPattern(app);
        end

        % Button pushed function: BrowseReferenceFileButton
        function LoadReferenceGainFile(app, event)
            % This function handles the loading of a reference antenna gain
            % file in the application. The file must conform to a specific
            % column format, which includes the following columns:
            %       - Theta (deg)
            %       - Phi (deg)
            %       - Frequency (MHz)
            %       - Gain (dBi)
            %       - Return Loss (dB)
            %       - Return Loss (deg)
            % The function attempts to load the reference gain data, and 
            % if successful, it displays the loaded file's path in the GUI. 
            % Additionally, it plots the reference antenna data.
            
            try 
                % Load the reference gain file.
                loadData(app, 'AntennaReference');

                % Check if the uploaded file is empty, or if the user
                % canceled the file selection.
                if isempty(app.ReferenceGainFile)
                    return;
                end

                % If the file presents no errors, the file path is loaded
                % into the file, and displayed to the user.
                app.ReferenceGainFileField.Value = evalin('base', 'loadedFilePath');
                plotReferenceAntenna(app);
            catch ME
                app.displayError(ME);
            end
        end

        % Button pushed function: PlotReferenceGainButton
        function PlotReferenceGainFile(app, event)
            % This function plots the reference gain data that has been 
            % loaded into the application. It extracts all relevant RF data  
            % from the reference gain file and displays them on the 
            % corresponding plots for the user to visualize.

            try
                plotReferenceAntenna(app);
            catch ME
                app.displayError(ME);
            end
        end

        % Close request function: UIFigure
        function closeupFcn(app, event)
            % This function handles the app's closure behavior. When the 
            % user attempts to close the app, the function prompts them
            % with a confirmation dialog. If the user confirms the closure,
            % the function will clean up by closing all active instrument 
            % connections. If the user cancels the closure, the function 
            % does nothing and returns to the app.

            instrumentNames = {'PowerSupplyA',... 
                               'PowerSupplyB',...
                               'SignalGenerator',...
                               'SpectrumAnalyzer',...
                               'VNA',...
                               'EMCenter',...
                               'LinearSlider'
            };
            question = 'Close ARES App?';
            choice = uiconfirm(app.UIFigure, question, 'Confirm Close', 'Options', {'Yes', 'No'});

            switch choice
                case 'Yes'
                    for i = 1:numel(instrumentNames)
                        instrumentName = instrumentNames{i};
                        if ~isempty(app.(instrumentName))
                            flush(app.(instrumentName));
                            app.(instrumentName) = [];
                        end
                    end
                    app.delete
                case 'No'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.MainTabs = uitabgroup(app.MainGrid);
            app.InfoTab = uitab(app.MainTabs);
            app.InfoTab.Title = 'Info';
            app.InfoGridLayout = uigridlayout(app.InfoTab);
            app.Image = uiimage(app.InfoGridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'support', 'ARES Icon.png');
            app.AppName = uilabel(app.InfoGridLayout);
            app.AppName.Text = 'Automated Radio Evaluation Suite';
            app.Relase = uilabel(app.InfoGridLayout);
            app.Relase.Text = 'V0.9.1 BETA Relase';
            app.Repository = uihyperlink(app.InfoGridLayout);
            app.Repository.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite';
            app.Repository.Text = 'GitHub Repo';
            app.Author = uilabel(app.InfoGridLayout);
            app.Author.Text = {'Author: Jose Abrahan Bolaños Vargas'; 'Mentor: Alex D. Santiago Vargas'; 'PI: Dimitrios Peroulis'; 'Adaptive Radio Electronics and Sensors (ARES) Group'};
            app.PATutorial = uihyperlink(app.InfoGridLayout);
            app.PATutorial.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#pa-measurement-tutorial';
            app.PATutorial.Text = 'PA Measurement Tutorial';
            app.AntTutorial = uihyperlink(app.InfoGridLayout);
            app.AntTutorial.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite?tab=readme-ov-file#antenna-measurement-tutorial';
            app.AntTutorial.Text = 'Antenna Tutorial';
            app.README = uihyperlink(app.InfoGridLayout);
            app.README.URL = 'https://github.com/bolanosv/AutomatedRadioEvaluationSuite/blob/main/README.md';
            app.README.Text = 'README';
            app.PowerAmplifierTab = uitab(app.MainTabs);
            app.PowerAmplifierTab.Title = 'Power Amplifier ';
            app.PAGridLayout = uigridlayout(app.PowerAmplifierTab);
            app.PAResultsTabs = uitabgroup(app.PAGridLayout);
            app.SingleTab = uitab(app.PAResultsTabs);
            app.SingleTab.Title = 'Single';
            app.PAPlotSingleGridLayout = uigridlayout(app.SingleTab);
            app.SingleFrequencyPAPlot = uiaxes(app.PAPlotSingleGridLayout);
            title(app.SingleFrequencyPAPlot, 'PA Performance Metrics')
            xlabel(app.SingleFrequencyPAPlot, 'Output Power (dBm)')
            zlabel(app.SingleFrequencyPAPlot, 'Z')
            app.SweepTab = uitab(app.PAResultsTabs);
            app.SweepTab.Title = 'Sweep';
            app.PAPlotSweepGridLayout = uigridlayout(app.SweepTab);
            app.GainvsOutputPowerPlot = uiaxes(app.PAPlotSweepGridLayout);
            title(app.GainvsOutputPowerPlot, 'Gain vs. Output Power')
            xlabel(app.GainvsOutputPowerPlot, 'Output Power (dBm)')
            ylabel(app.GainvsOutputPowerPlot, 'Gain (dB)')
            zlabel(app.GainvsOutputPowerPlot, 'Z')
            app.PeakGainPlot = uiaxes(app.PAPlotSweepGridLayout);
            title(app.PeakGainPlot, 'Peak Gain')
            xlabel(app.PeakGainPlot, 'Frequency (GHz)')
            ylabel(app.PeakGainPlot, 'Gain (dB)')
            zlabel(app.PeakGainPlot, 'Z')
            app.PeakDEPAEPlot = uiaxes(app.PAPlotSweepGridLayout);
            title(app.PeakDEPAEPlot, 'Peak DE and PAE')
            xlabel(app.PeakDEPAEPlot, 'Frequency (GHz)')
            ylabel(app.PeakDEPAEPlot, 'Efficiency (%)')
            zlabel(app.PeakDEPAEPlot, 'Z')
            app.CompressionPointsPlot = uiaxes(app.PAPlotSweepGridLayout);
            title(app.CompressionPointsPlot, 'Saturation Power and Compression Points')
            xlabel(app.CompressionPointsPlot, 'Frequency (GHz)')
            ylabel(app.CompressionPointsPlot, 'Output Power (dBm)')
            zlabel(app.CompressionPointsPlot, 'Z')
            app.PATestSettingsPanel = uipanel(app.PAGridLayout);
            app.PATestSettingsPanel.Title = 'Test and Plot Settings';
            app.PATestSettingsGridLayout = uigridlayout(app.PATestSettingsPanel);
            app.StartPAMeasurement = uibutton(app.PATestSettingsGridLayout, 'push');
            app.StartPAMeasurement.ButtonPushedFcn = createCallbackFcn(app, @PAStartMeasurement, true);
            app.StartPAMeasurement.Text = 'Start Test';
            app.LoadPAData = uibutton(app.PATestSettingsGridLayout, 'push');
            app.LoadPAData.ButtonPushedFcn = createCallbackFcn(app, @PALoadData, true);
            app.LoadPAData.Text = 'Load Test';
            app.PlotPAData = uibutton(app.PATestSettingsGridLayout, 'push');
            app.PlotPAData.ButtonPushedFcn = createCallbackFcn(app, @PAPlotMeasurement, true);
            app.PlotPAData.Text = 'Plot Results';
            app.PSUChannelDropDownLabel = uilabel(app.PATestSettingsGridLayout);
            app.PSUChannelDropDownLabel.Text = 'PSU Channel';
            app.PSUChannelDropDown = uidropdown(app.PATestSettingsGridLayout);
            app.PSUChannelDropDown.Items = {''};
            app.PSUChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChannelValueChanged, true);
            app.PSUChannelDropDown.Value = '';
            app.ChannelVoltageDropDownLabel = uilabel(app.PATestSettingsGridLayout);
            app.ChannelVoltageDropDownLabel.Text = 'Channel Voltage';
            app.ChannelVoltageDropDown = uidropdown(app.PATestSettingsGridLayout);
            app.ChannelVoltageDropDown.Items = {''};
            app.ChannelVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @PAChannelVoltageValueChanged, true);
            app.ChannelVoltageDropDown.Value = '';
            app.FrequencySingleDropDownLabel = uilabel(app.PATestSettingsGridLayout);
            app.FrequencySingleDropDownLabel.Text = 'Frequency (Single)';
            app.FrequencySingleDropDown = uidropdown(app.PATestSettingsGridLayout);
            app.FrequencySingleDropDown.Items = {''};
            app.FrequencySingleDropDown.ValueChangedFcn = createCallbackFcn(app, @PASingleFrequencyValueChanged, true);
            app.FrequencySingleDropDown.Value = '';
            app.PASettingsPanel = uitabgroup(app.PAGridLayout);
            app.InstrumentsTab = uitab(app.PASettingsPanel);
            app.InstrumentsTab.Title = 'Instruments';
            app.PAInstrumentGridLayout = uigridlayout(app.InstrumentsTab);
            app.PowerSupplyAStatusLabel = uilabel(app.PAInstrumentGridLayout);
            app.PowerSupplyAStatusLabel.Text = 'Status:';
            app.PowerSupplyADropDown = uidropdown(app.PAInstrumentGridLayout);
            app.PowerSupplyADropDown.Items = {''};
            app.PowerSupplyADropDown.Editable = 'on';
            app.PowerSupplyADropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.PowerSupplyADropDown.Placeholder = 'Enter address:';
            app.PowerSupplyADropDown.Value = '';
            app.PowerSupplyBStatusLabel = uilabel(app.PAInstrumentGridLayout);
            app.PowerSupplyBStatusLabel.Text = 'Status:';
            app.PowerSupplyBDropDown = uidropdown(app.PAInstrumentGridLayout);
            app.PowerSupplyBDropDown.Items = {};
            app.PowerSupplyBDropDown.Editable = 'on';
            app.PowerSupplyBDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.PowerSupplyBDropDown.Placeholder = 'Enter address:';
            app.PowerSupplyBDropDown.Value = {};
            app.SignalGeneratorStatusLabel = uilabel(app.PAInstrumentGridLayout);
            app.SignalGeneratorStatusLabel.Text = 'Status:';
            app.SignalGeneratorDropDown = uidropdown(app.PAInstrumentGridLayout);
            app.SignalGeneratorDropDown.Items = {};
            app.SignalGeneratorDropDown.Editable = 'on';
            app.SignalGeneratorDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.SignalGeneratorDropDown.Placeholder = 'Enter address:';
            app.SignalGeneratorDropDown.Value = {};
            app.SpectrumAnalyzerStatusLabel = uilabel(app.PAInstrumentGridLayout);
            app.SpectrumAnalyzerStatusLabel.Text = 'Status:';
            app.PowerSupplyALabel = uilabel(app.PAInstrumentGridLayout);
            app.PowerSupplyALabel.Text = 'Power Supply A';
            app.PowerSupplyBLabel = uilabel(app.PAInstrumentGridLayout);
            app.PowerSupplyBLabel.Text = 'Power Supply B';
            app.SignalGeneratorLabel = uilabel(app.PAInstrumentGridLayout);
            app.SignalGeneratorLabel.Text = 'Signal Generator';
            app.SpectrumAnalyzerDropDown = uidropdown(app.PAInstrumentGridLayout);
            app.SpectrumAnalyzerDropDown.Items = {};
            app.SpectrumAnalyzerDropDown.Editable = 'on';
            app.SpectrumAnalyzerDropDown.ValueChangedFcn = createCallbackFcn(app, @PAInstrumentsAddressChanged, true);
            app.SpectrumAnalyzerDropDown.Placeholder = 'Enter address:';
            app.SpectrumAnalyzerDropDown.Value = {};
            app.PAMeasurementDelayValueField = uieditfield(app.PAInstrumentGridLayout, 'numeric');
            app.PAMeasurementDelayValueField.Limits = [0 Inf];
            app.PAMeasurementDelayValueField.Value = 0.1;
            app.PAConnectButton = uibutton(app.PAInstrumentGridLayout, 'push');
            app.PAConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PAConnectInstruments, true);
            app.PAConnectButton.Text = 'Connect';
            app.SpectrumAnalyzerLabel = uilabel(app.PAInstrumentGridLayout);
            app.SpectrumAnalyzerLabel.Text = 'Spectrum Analyzer';
            app.MeasurementDelaysEditFieldLabel = uilabel(app.PAInstrumentGridLayout);
            app.MeasurementDelaysEditFieldLabel.Text = 'Measurement Delay (s)';
            app.PADisconnectButton = uibutton(app.PAInstrumentGridLayout, 'push');
            app.PADisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @PADisconnectInstruments, true);
            app.PADisconnectButton.Text = 'Disconnect';
            app.PowerSupplyTab = uitab(app.PASettingsPanel);
            app.PowerSupplyTab.Title = 'Power Supply';
            app.PAPSUGridLayout = uigridlayout(app.PowerSupplyTab);
            app.PowerSupplyModeDropDown = uidropdown(app.PAPSUGridLayout);
            app.PowerSupplyModeDropDown.Items = {'No Supply', 'Single Supply', 'Dual Supply', 'Quad Supply'};
            app.PowerSupplyModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChangeMode, true);
            app.PowerSupplyModeDropDown.Value = 'Single Supply';
            app.PowerSupplyChannelDropDown = uidropdown(app.PAPSUGridLayout);
            app.PowerSupplyChannelDropDown.Items = {''};
            app.PowerSupplyChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PSUChannelDropDownOpening, true);
            app.PowerSupplyChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @PSUChangeChannel, true);
            app.PowerSupplyChannelDropDown.Value = '';
            app.DrainGateSwitch = uiswitch(app.PAPSUGridLayout, 'slider');
            app.DrainGateSwitch.Items = {'Drain', 'Gate'};
            app.DrainGateSwitch.ValueChangedFcn = createCallbackFcn(app, @PSUChangeDrainGate, true);
            app.DrainGateSwitch.Value = 'Drain';
            app.SingleSweepSwitch = uiswitch(app.PAPSUGridLayout, 'slider');
            app.SingleSweepSwitch.Items = {'Single', 'Sweep'};
            app.SingleSweepSwitch.ValueChangedFcn = createCallbackFcn(app, @PSUChangeSingleSweepVoltages, true);
            app.SingleSweepSwitch.Value = 'Single';
            app.SupplyCurrent = uieditfield(app.PAPSUGridLayout, 'numeric');
            app.SupplyCurrent.ValueChangedFcn = createCallbackFcn(app, @PSUSetLimitingCurrent, true);
            app.LimitingCurrentALabel = uilabel(app.PAPSUGridLayout);
            app.LimitingCurrentALabel.Text = 'Current Limit (A)';
            app.SupplyModeLabel = uilabel(app.PAPSUGridLayout);
            app.SupplyModeLabel.Text = 'Supply Mode';
            app.SupplyChannelDropDownLabel = uilabel(app.PAPSUGridLayout);
            app.SupplyChannelDropDownLabel.Text = 'Supply Channel';
            app.StepVoltageVEditFieldLabel = uilabel(app.PAPSUGridLayout);
            app.StepVoltageVEditFieldLabel.Enable = 'off';
            app.StepVoltageVEditFieldLabel.Text = 'Step Voltage (V)';
            app.StepVoltage = uieditfield(app.PAPSUGridLayout, 'numeric');
            app.StepVoltage.ValueChangedFcn = createCallbackFcn(app, @PSUSetStepVoltage, true);
            app.StepVoltage.Enable = 'off';
            app.StopVoltageVEditFieldLabel = uilabel(app.PAPSUGridLayout);
            app.StopVoltageVEditFieldLabel.Enable = 'off';
            app.StopVoltageVEditFieldLabel.Text = 'Stop Voltage (V)';
            app.StopVoltage = uieditfield(app.PAPSUGridLayout, 'numeric');
            app.StopVoltage.ValueChangedFcn = createCallbackFcn(app, @PSUSetStopVoltage, true);
            app.StopVoltage.Enable = 'off';
            app.StartVoltageVEditFieldLabel = uilabel(app.PAPSUGridLayout);
            app.StartVoltageVEditFieldLabel.Text = 'Start Voltage (V)';
            app.StartVoltage = uieditfield(app.PAPSUGridLayout, 'numeric');
            app.StartVoltage.ValueChangedFcn = createCallbackFcn(app, @PSUSetStartVoltage, true);
            app.SignalAnalysisTab = uitab(app.PASettingsPanel);
            app.SignalAnalysisTab.Title = 'Signal Analysis';
            app.PASAGridLayout = uigridlayout(app.SignalAnalysisTab);
            app.MeasurementTypeDropDown = uidropdown(app.PASAGridLayout);
            app.MeasurementTypeDropDown.Items = {'Single Frequency', 'Sweep Frequencies'};
            app.MeasurementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PAChangeFrequencyMeasurementMode, true);
            app.MeasurementTypeDropDown.Value = 'Single Frequency';
            app.StartFrequencyMHzLabel = uilabel(app.PASAGridLayout);
            app.StartFrequencyMHzLabel.Text = 'Start Frequency (MHz)';
            app.StartFrequency = uieditfield(app.PASAGridLayout, 'numeric');
            app.StartFrequency.ValueDisplayFormat = '%11.0f';
            app.StartFrequency.Value = 1000;
            app.EndFrequencyMHzLabel = uilabel(app.PASAGridLayout);
            app.EndFrequencyMHzLabel.Enable = 'off';
            app.EndFrequencyMHzLabel.Text = 'End Frequency (MHz)';
            app.EndFrequency = uieditfield(app.PASAGridLayout, 'numeric');
            app.EndFrequency.ValueDisplayFormat = '%11.0f';
            app.EndFrequency.Enable = 'off';
            app.EndFrequency.Value = 2000;
            app.StartPowerdBmEditFieldLabel = uilabel(app.PASAGridLayout);
            app.StartPowerdBmEditFieldLabel.Text = 'Start Power (dBm)';
            app.StartPower = uieditfield(app.PASAGridLayout, 'numeric');
            app.StartPower.Value = -30;
            app.EndPowerdBmEditFieldLabel = uilabel(app.PASAGridLayout);
            app.EndPowerdBmEditFieldLabel.Text = 'End Power (dBm)';
            app.EndPower = uieditfield(app.PASAGridLayout, 'numeric');
            app.PowerStepdBmEditField = uilabel(app.PASAGridLayout);
            app.PowerStepdBmEditField.Text = 'Power Step (dBm)';
            app.PowerStep = uieditfield(app.PASAGridLayout, 'numeric');
            app.PowerStep.Value = 1;
            app.SpanNameField = uilabel(app.PASAGridLayout);
            app.SpanNameField.Text = 'Span (MHz)';
            app.SpanValueField = uieditfield(app.PASAGridLayout, 'numeric');
            app.SpanValueField.ValueDisplayFormat = '%11.0f';
            app.SpanValueField.Value = 40;
            app.SweepPointsNameField = uilabel(app.PASAGridLayout);
            app.SweepPointsNameField.Text = 'Sweep Points';
            app.SweepPointsValueField = uieditfield(app.PASAGridLayout, 'numeric');
            app.SweepPointsValueField.Limits = [0 Inf];
            app.SweepPointsValueField.Value = 1001;
            app.ReferenceLevelNameField = uilabel(app.PASAGridLayout);
            app.ReferenceLevelNameField.Text = 'Reference Level (dB)';
            app.ReferenceLevelValueField = uieditfield(app.PASAGridLayout, 'numeric');
            app.MeasurementTypeDropDownLabel = uilabel(app.PASAGridLayout);
            app.MeasurementTypeDropDownLabel.Text = 'Measurement Type';
            app.SignalGeneratorSettingsLabel = uilabel(app.PASAGridLayout);
            app.SignalGeneratorSettingsLabel.Text = 'Signal Generator Settings';
            app.SpectrumAnalyzerSettingsLabel = uilabel(app.PASAGridLayout);
            app.SpectrumAnalyzerSettingsLabel.Text = 'Spectrum Analyzer Settings';
            app.StepFrequencyMHzEditFieldLabel = uilabel(app.PASAGridLayout);
            app.StepFrequencyMHzEditFieldLabel.Enable = 'off';
            app.StepFrequencyMHzEditFieldLabel.Text = 'Step Frequency (MHz)';
            app.StepFrequency = uieditfield(app.PASAGridLayout, 'numeric');
            app.StepFrequency.ValueDisplayFormat = '%11.0f';
            app.StepFrequency.Enable = 'off';
            app.StepFrequency.Value = 100;
            app.CalibrationTab = uitab(app.PASettingsPanel);
            app.CalibrationTab.Title = 'Calibration';
            app.PACalGridLayout = uigridlayout(app.CalibrationTab);
            app.CalibrationModeDropDown = uidropdown(app.PACalGridLayout);
            app.CalibrationModeDropDown.Items = {'None', 'Fixed Attenuation', 'Small Signal', 'Small + Large Signal'};
            app.CalibrationModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PAChangeCalibrationMode, true);
            app.CalibrationModeDropDown.Value = 'None';
            app.InputAttenuationValueField = uieditfield(app.PACalGridLayout, 'numeric');
            app.OutputAttenuationValueField = uieditfield(app.PACalGridLayout, 'numeric');
            app.LoadInputSmallSignalButton = uibutton(app.PACalGridLayout, 'push');
            app.LoadInputSmallSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSmallSignalInputS2PFile, true);
            app.LoadInputSmallSignalButton.Text = 'Load Input S-Parameters';
            app.InputSmallSignalFilenameField = uieditfield(app.PACalGridLayout, 'text');
            app.InputSmallSignalFilenameField.Editable = 'off';
            app.InputSmallSignalFilenameField.Placeholder = '.s2p';
            app.LoadOuputSmallSignalButton = uibutton(app.PACalGridLayout, 'push');
            app.LoadOuputSmallSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSmallSignalOutputS2PFile, true);
            app.LoadOuputSmallSignalButton.Text = 'Load Output S-Parameters';
            app.CalibrationModeLabel = uilabel(app.PACalGridLayout);
            app.CalibrationModeLabel.Text = 'Calibration Mode';
            app.InputAttenuationdBLabel = uilabel(app.PACalGridLayout);
            app.InputAttenuationdBLabel.Text = 'Input Attenuation (dB)';
            app.OutputSmallSignalFilenameField = uieditfield(app.PACalGridLayout, 'text');
            app.OutputSmallSignalFilenameField.Editable = 'off';
            app.OutputSmallSignalFilenameField.Placeholder = '.s2p';
            app.LoadLargeSignalDriverResponseButton = uibutton(app.PACalGridLayout, 'push');
            app.LoadLargeSignalDriverResponseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLargeSignalDriverResponseFile, true);
            app.LoadLargeSignalDriverResponseButton.Text = 'Load Large-Signal Driver Response';
            app.LargeSignalDriverResponseFilenameField = uieditfield(app.PACalGridLayout, 'text');
            app.LargeSignalDriverResponseFilenameField.Editable = 'off';
            app.LargeSignalDriverResponseFilenameField.Placeholder = '.xlsx,.csv';
            app.OutputAttenuationdBEditFieldLabel = uilabel(app.PACalGridLayout);
            app.OutputAttenuationdBEditFieldLabel.Text = 'Output Attenuation (dB)';
            app.SmallSignalFrequencyResponseLabel = uilabel(app.PACalGridLayout);
            app.SmallSignalFrequencyResponseLabel.Text = 'Small-Signal Frequency Response';
            app.DriverResponseLabel = uilabel(app.PACalGridLayout);
            app.DriverResponseLabel.Text = 'Driver Response';
            app.FixedCalibrationLabel = uilabel(app.PACalGridLayout);
            app.FixedCalibrationLabel.Text = 'Fixed Calibration';
            app.AntennaTab = uitab(app.MainTabs);
            app.AntennaTab.Title = 'Antenna ';
            app.AntGridLayout = uigridlayout(app.AntennaTab);
            app.AntennaPattern = uitabgroup(app.AntGridLayout);
            app.AntennaGainTab = uitab(app.AntennaPattern);
            app.AntennaGainTab.Title = 'Antenna Gain';
            app.AntGainGridLayout = uigridlayout(app.AntennaGainTab);
            app.ReturnLossBoresightGain = uiaxes(app.AntGainGridLayout);
            title(app.ReturnLossBoresightGain, 'Return Loss vs. Frequency')
            xlabel(app.ReturnLossBoresightGain, 'Frequency (GHz)')
            ylabel(app.ReturnLossBoresightGain, 'RL (dB)')
            zlabel(app.ReturnLossBoresightGain, 'Z')
            app.GainvsFrequencyBoresightGain = uiaxes(app.AntGainGridLayout);
            title(app.GainvsFrequencyBoresightGain, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequencyBoresightGain, 'Frequency (GHz)')
            ylabel(app.GainvsFrequencyBoresightGain, 'Gain (dBi)')
            zlabel(app.GainvsFrequencyBoresightGain, 'Z')
            app.PlotReferenceGainButton = uibutton(app.AntGainGridLayout, 'push');
            app.PlotReferenceGainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotReferenceGainFile, true);
            app.PlotReferenceGainButton.Text = 'Plot Reference Gain';
            app.ReferenceGainFileField = uieditfield(app.AntGainGridLayout, 'text');
            app.ReferenceGainFileField.Editable = 'off';
            app.BrowseReferenceFileButton = uibutton(app.AntGainGridLayout, 'push');
            app.BrowseReferenceFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceGainFile, true);
            app.BrowseReferenceFileButton.Text = 'Browse Reference File';
            app.RadiationPatternTab = uitab(app.AntennaPattern);
            app.RadiationPatternTab.Title = '2D Radiation Pattern';
            app.AntPatternGridLayout = uigridlayout(app.RadiationPatternTab);
            app.ReturnLoss2D = uiaxes(app.AntPatternGridLayout);
            title(app.ReturnLoss2D, 'Return Loss')
            xlabel(app.ReturnLoss2D, 'Frequency (GHz)')
            ylabel(app.ReturnLoss2D, 'RL (dB)')
            zlabel(app.ReturnLoss2D, 'Z')
            app.GainvsFrequency2D = uiaxes(app.AntPatternGridLayout);
            title(app.GainvsFrequency2D, 'Gain vs. Frequency')
            xlabel(app.GainvsFrequency2D, 'Frequency (GHz)')
            ylabel(app.GainvsFrequency2D, 'Gain (dBi)')
            zlabel(app.GainvsFrequency2D, 'Z')
            app.GainvsAngle2D = uiaxes(app.AntPatternGridLayout);
            title(app.GainvsAngle2D, 'Gain vs. Angle')
            xlabel(app.GainvsAngle2D, 'Angle (°)')
            ylabel(app.GainvsAngle2D, 'Gain (dBi)')
            zlabel(app.GainvsAngle2D, 'Z')
            app.RadiationPattern3DTab = uitab(app.AntennaPattern);
            app.RadiationPattern3DTab.Title = '3D Radiation Pattern';
            app.GridLayout = uigridlayout(app.RadiationPattern3DTab);
            app.AntennaSettings = uitabgroup(app.AntGridLayout);
            app.AntInstrumentsTab = uitab(app.AntennaSettings);
            app.AntInstrumentsTab.Title = 'Instruments';
            app.AntInstrumentGridLayout = uigridlayout(app.AntInstrumentsTab);
            app.VNALabel = uilabel(app.AntInstrumentGridLayout);
            app.VNALabel.Text = 'VNA';
            app.VNAStatusLabel = uilabel(app.AntInstrumentGridLayout);
            app.VNAStatusLabel.Text = 'Status:';
            app.VNADropDown = uidropdown(app.AntInstrumentGridLayout);
            app.VNADropDown.Items = {};
            app.VNADropDown.Editable = 'on';
            app.VNADropDown.Placeholder = 'Enter address:';
            app.VNADropDown.Value = {};
            app.EMCenterDropDown = uidropdown(app.AntInstrumentGridLayout);
            app.EMCenterDropDown.Items = {};
            app.EMCenterDropDown.Editable = 'on';
            app.EMCenterDropDown.Placeholder = 'Enter address:';
            app.EMCenterDropDown.Value = {};
            app.EMCenterLabel = uilabel(app.AntInstrumentGridLayout);
            app.EMCenterLabel.Text = 'EMCenter';
            app.EMCenterStatusLabel = uilabel(app.AntInstrumentGridLayout);
            app.EMCenterStatusLabel.Text = 'Status:';
            app.LinearSliderDropDown = uidropdown(app.AntInstrumentGridLayout);
            app.LinearSliderDropDown.Items = {};
            app.LinearSliderDropDown.Editable = 'on';
            app.LinearSliderDropDown.Placeholder = 'Enter address:';
            app.LinearSliderDropDown.Value = {};
            app.LinearSliderLabel = uilabel(app.AntInstrumentGridLayout);
            app.LinearSliderLabel.Text = 'Linear Slider';
            app.LinearSliderStatusLabel = uilabel(app.AntInstrumentGridLayout);
            app.LinearSliderStatusLabel.Text = 'Status:';
            app.AntennaMeasurementDelayValueField = uieditfield(app.AntInstrumentGridLayout, 'numeric');
            app.AntennaMeasurementDelayValueField.Value = 0.1;
            app.AntennaConnectButton = uibutton(app.AntInstrumentGridLayout, 'push');
            app.AntennaConnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaConnectInstruments, true);
            app.AntennaConnectButton.Text = 'Connect';
            app.AntennaDisconnectButton = uibutton(app.AntInstrumentGridLayout, 'push');
            app.AntennaDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaDisconnectInstruments, true);
            app.AntennaDisconnectButton.Text = 'Disconnect';
            app.MeasurementDelaysLabel = uilabel(app.AntInstrumentGridLayout);
            app.MeasurementDelaysLabel.Text = 'Measurement Delay (s)';
            app.VNATab = uitab(app.AntennaSettings);
            app.VNATab.Title = 'VNA';
            app.AntVNAGridLayout = uigridlayout(app.VNATab);
            app.StartFrequectSpanMHzLabel_15 = uilabel(app.AntVNAGridLayout);
            app.StartFrequectSpanMHzLabel_15.Text = 'Start Frequency (MHz)';
            app.VNAMeasurement = uidropdown(app.AntVNAGridLayout);
            app.VNAMeasurement.Items = {'Gain Characterization', 'Pattern Measurement'};
            app.VNAMeasurement.Value = 'Gain Characterization';
            app.VNASweepPoints = uieditfield(app.AntVNAGridLayout, 'numeric');
            app.VNASweepPoints.Limits = [0 Inf];
            app.VNASweepPoints.AllowEmpty = 'on';
            app.VNASweepPoints.Value = [];
            app.VNAStartFrequency = uieditfield(app.AntVNAGridLayout, 'numeric');
            app.VNAStartFrequency.Limits = [0 Inf];
            app.VNAStartFrequency.ValueDisplayFormat = '%11.0f';
            app.VNAStartFrequency.AllowEmpty = 'on';
            app.VNAStartFrequency.Value = [];
            app.EndFrequencyMHzLabel_6 = uilabel(app.AntVNAGridLayout);
            app.EndFrequencyMHzLabel_6.Text = 'End Frequency (MHz)';
            app.VNAEndFrequency = uieditfield(app.AntVNAGridLayout, 'numeric');
            app.VNAEndFrequency.Limits = [0 Inf];
            app.VNAEndFrequency.ValueDisplayFormat = '%11.0f ';
            app.VNAEndFrequency.AllowEmpty = 'on';
            app.VNAEndFrequency.Value = [];
            app.MeasurementDropDown_2Label = uilabel(app.AntVNAGridLayout);
            app.MeasurementDropDown_2Label.Text = 'Measurement';
            app.SweepPointsLabel = uilabel(app.AntVNAGridLayout);
            app.SweepPointsLabel.Text = 'Sweep Points';
            app.Table = uitab(app.AntennaSettings);
            app.Table.Title = 'Table';
            app.AntTableGridLayout = uigridlayout(app.Table);
            app.StopTableButton = uibutton(app.AntTableGridLayout, 'push');
            app.StopTableButton.ButtonPushedFcn = createCallbackFcn(app, @TableStopMovement, true);
            app.StopTableButton.Text = 'Stop Table';
            app.TableNewAngle = uieditfield(app.AntTableGridLayout, 'numeric');
            app.TableNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.MovetoAngleButton = uibutton(app.AntTableGridLayout, 'push');
            app.MovetoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TableChangeAngle, true);
            app.MovetoAngleButton.Text = 'Move to Angle';
            app.StartFrequectSpanMHzLabel_11 = uilabel(app.AntTableGridLayout);
            app.StartFrequectSpanMHzLabel_11.Text = 'Start Angle ';
            app.TableStartAngle = uieditfield(app.AntTableGridLayout, 'numeric');
            app.TableStartAngle.Limits = [-180 180];
            app.TableStartAngle.AllowEmpty = 'on';
            app.TableStartAngle.Placeholder = '(deg)';
            app.TableStartAngle.Value = [];
            app.StartFrequectSpanMHzLabel_12 = uilabel(app.AntTableGridLayout);
            app.StartFrequectSpanMHzLabel_12.Text = 'Angle Step Size ';
            app.TableStepAngle = uieditfield(app.AntTableGridLayout, 'numeric');
            app.TableStepAngle.AllowEmpty = 'on';
            app.TableStepAngle.Placeholder = '(deg)';
            app.TableStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_13 = uilabel(app.AntTableGridLayout);
            app.StartFrequectSpanMHzLabel_13.Text = 'End Angle ';
            app.TableEndAngle = uieditfield(app.AntTableGridLayout, 'numeric');
            app.TableEndAngle.Limits = [-180 180];
            app.TableEndAngle.AllowEmpty = 'on';
            app.TableEndAngle.Placeholder = '(deg)';
            app.TableEndAngle.Value = [];
            app.TableSpeedSliderLabel = uilabel(app.AntTableGridLayout);
            app.TableSpeedSliderLabel.Text = 'Table Speed';
            app.TableSpeedSlider = uislider(app.AntTableGridLayout);
            app.TableSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @TableChangeSpeed, true);
            app.TableSpeedSlider.Value = 50;
            app.ThetaSingleSweepSwitch = uiswitch(app.AntTableGridLayout, 'slider');
            app.ThetaSingleSweepSwitch.Items = {'Single', 'Sweep'};
            app.ThetaSingleSweepSwitch.ValueChangedFcn = createCallbackFcn(app, @ThetaChangeSingleSweepSwitchAngles, true);
            app.ThetaSingleSweepSwitch.Value = 'Sweep';
            app.TableSpeedSliderLabel_2 = uilabel(app.AntTableGridLayout);
            app.TableSpeedSliderLabel_2.Text = 'Theta Scan';
            app.TowerTab = uitab(app.AntennaSettings);
            app.TowerTab.Title = 'Tower';
            app.AntTowerGridLayout = uigridlayout(app.TowerTab);
            app.StopTowerButton = uibutton(app.AntTowerGridLayout, 'push');
            app.StopTowerButton.ButtonPushedFcn = createCallbackFcn(app, @TowerStopMovement, true);
            app.StopTowerButton.Text = 'Stop Tower';
            app.TowerNewAngle = uieditfield(app.AntTowerGridLayout, 'numeric');
            app.TowerNewAngle.Limits = [-180 180];
            app.TowerNewAngle.ValueDisplayFormat = '%11.4g (deg)';
            app.MoveTowertoAngleButton = uibutton(app.AntTowerGridLayout, 'push');
            app.MoveTowertoAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TowerChangeAngle, true);
            app.MoveTowertoAngleButton.Text = 'Move to Angle';
            app.StartFrequectSpanMHzLabel_16 = uilabel(app.AntTowerGridLayout);
            app.StartFrequectSpanMHzLabel_16.Text = 'Start Angle ';
            app.TowerStartAngle = uieditfield(app.AntTowerGridLayout, 'numeric');
            app.TowerStartAngle.Limits = [0 360];
            app.TowerStartAngle.AllowEmpty = 'on';
            app.TowerStartAngle.Placeholder = '(deg)';
            app.StartFrequectSpanMHzLabel_17 = uilabel(app.AntTowerGridLayout);
            app.StartFrequectSpanMHzLabel_17.Enable = 'off';
            app.StartFrequectSpanMHzLabel_17.Text = 'Angle Step Size ';
            app.TowerStepAngle = uieditfield(app.AntTowerGridLayout, 'numeric');
            app.TowerStepAngle.AllowEmpty = 'on';
            app.TowerStepAngle.Enable = 'off';
            app.TowerStepAngle.Placeholder = '(deg)';
            app.TowerStepAngle.Value = [];
            app.StartFrequectSpanMHzLabel_18 = uilabel(app.AntTowerGridLayout);
            app.StartFrequectSpanMHzLabel_18.Enable = 'off';
            app.StartFrequectSpanMHzLabel_18.Text = 'End Angle ';
            app.TowerEndAngle = uieditfield(app.AntTowerGridLayout, 'numeric');
            app.TowerEndAngle.Limits = [0 360];
            app.TowerEndAngle.AllowEmpty = 'on';
            app.TowerEndAngle.Enable = 'off';
            app.TowerEndAngle.Placeholder = '(deg)';
            app.TowerEndAngle.Value = [];
            app.TowerSpeedSliderLabel = uilabel(app.AntTowerGridLayout);
            app.TowerSpeedSliderLabel.Text = 'Tower Speed';
            app.TowerSpeedSlider = uislider(app.AntTowerGridLayout);
            app.TowerSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @TowerChangeSpeed, true);
            app.TowerSpeedSlider.Value = 50;
            app.PhiSingleSweepSwitch = uiswitch(app.AntTowerGridLayout, 'slider');
            app.PhiSingleSweepSwitch.Items = {'Single', 'Sweep'};
            app.PhiSingleSweepSwitch.ValueChangedFcn = createCallbackFcn(app, @PhiChangeSingleSweepAngles, true);
            app.PhiSingleSweepSwitch.Value = 'Single';
            app.TableSpeedSliderLabel_3 = uilabel(app.AntTowerGridLayout);
            app.TableSpeedSliderLabel_3.Text = 'Phi Scan';
            app.LinearSliderTab = uitab(app.AntennaSettings);
            app.LinearSliderTab.Title = 'Linear Slider';
            app.AntSliderGridLayout = uigridlayout(app.LinearSliderTab);
            app.AntennaOffsetLabel = uilabel(app.AntSliderGridLayout);
            app.AntennaOffsetLabel.Text = 'Antenna Offset ';
            app.AntennaPhysicalSize = uieditfield(app.AntSliderGridLayout, 'numeric');
            app.AntennaPhysicalSize.Limits = [0 Inf];
            app.AntennaPhysicalSize.ValueDisplayFormat = '%11.4g (cm)';
            app.AntennaPhysicalSize.AllowEmpty = 'on';
            app.AntennaPhysicalSize.ValueChangedFcn = createCallbackFcn(app, @LinearSliderApplyAntennaPhysicalOffset, true);
            app.AntennaPhysicalSize.Value = [];
            app.SpeedPresetDropDown = uidropdown(app.AntSliderGridLayout);
            app.SpeedPresetDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.SpeedPresetDropDown.ValueChangedFcn = createCallbackFcn(app, @LinearSliderChangeSpeed, true);
            app.SpeedPresetDropDown.Value = '1';
            app.SliderPositioncmLabel = uilabel(app.AntSliderGridLayout);
            app.SliderPositioncmLabel.Text = 'Slider Current Position';
            app.LinearSliderCurrentPosition = uieditfield(app.AntSliderGridLayout, 'numeric');
            app.LinearSliderCurrentPosition.ValueDisplayFormat = '%11.4g (cm)';
            app.LinearSliderCurrentPosition.AllowEmpty = 'on';
            app.LinearSliderCurrentPosition.Editable = 'off';
            app.LinearSliderCurrentPosition.Value = [];
            app.MovetoPositionButton = uibutton(app.AntSliderGridLayout, 'push');
            app.MovetoPositionButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderChangePosition, true);
            app.MovetoPositionButton.Text = 'Move to Position';
            app.LinearSliderNewPosition = uieditfield(app.AntSliderGridLayout, 'numeric');
            app.LinearSliderNewPosition.Limits = [0 200];
            app.LinearSliderNewPosition.Placeholder = 'cm';
            app.CurrentSpacingLabel = uilabel(app.AntSliderGridLayout);
            app.CurrentSpacingLabel.Text = 'Current Spacing ';
            app.AntennaCurrentSpacing = uieditfield(app.AntSliderGridLayout, 'numeric');
            app.AntennaCurrentSpacing.ValueDisplayFormat = '%11.4g (m)';
            app.AntennaCurrentSpacing.AllowEmpty = 'on';
            app.AntennaCurrentSpacing.Editable = 'off';
            app.AntennaCurrentSpacing.Value = [];
            app.SpeedPresetDropDownLabel = uilabel(app.AntSliderGridLayout);
            app.SpeedPresetDropDownLabel.Text = 'Speed Preset';
            app.SliderHoming = uibutton(app.AntSliderGridLayout, 'push');
            app.SliderHoming.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderHomingMovement, true);
            app.SliderHoming.Text = 'Home';
            app.SliderScan = uibutton(app.AntSliderGridLayout, 'push');
            app.SliderScan.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderScanMovement, true);
            app.SliderScan.Text = 'Scan';
            app.StopLinearSliderButton = uibutton(app.AntSliderGridLayout, 'push');
            app.StopLinearSliderButton.ButtonPushedFcn = createCallbackFcn(app, @LinearSliderStopMovement, true);
            app.StopLinearSliderButton.Text = 'Stop Linear Slider';
            app.TestandPlotSettingsPanel = uipanel(app.AntGridLayout);
            app.TestandPlotSettingsPanel.Title = 'Test and Plot Settings';
            app.AntTestSettingsGridLayout = uigridlayout(app.TestandPlotSettingsPanel);
            app.PlotFrequencyMHzDropDownLabel = uilabel(app.AntTestSettingsGridLayout);
            app.PlotFrequencyMHzDropDownLabel.Text = 'Plot Frequency (MHz)';
            app.PlotFrequencyMHzDropDown = uidropdown(app.AntTestSettingsGridLayout);
            app.PlotFrequencyMHzDropDown.Items = {''};
            app.PlotFrequencyMHzDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedFrequencyValue, true);
            app.PlotFrequencyMHzDropDown.Value = '';
            app.PlottingAngleLabel = uilabel(app.AntTestSettingsGridLayout);
            app.PlottingAngleLabel.Text = 'Plot Theta (°)';
            app.PlotThetaDropDown = uidropdown(app.AntTestSettingsGridLayout);
            app.PlotThetaDropDown.Items = {''};
            app.PlotThetaDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedAngleValue, true);
            app.PlotThetaDropDown.Value = '';
            app.StartTestButton = uibutton(app.AntTestSettingsGridLayout, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStartMeasurement, true);
            app.StartTestButton.Text = 'Start Test';
            app.PlotResultsButton = uibutton(app.AntTestSettingsGridLayout, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaPlotMeasurement, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.LoadResultsButton = uibutton(app.AntTestSettingsGridLayout, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaLoadData, true);
            app.LoadResultsButton.Text = 'Load Results';
            app.StopTestButton = uibutton(app.AntTestSettingsGridLayout, 'push');
            app.StopTestButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaStopMeasurement, true);
            app.StopTestButton.Text = 'Stop Test';
            app.PlotPhiDropDownLabel = uilabel(app.AntTestSettingsGridLayout);
            app.PlotPhiDropDownLabel.Text = 'Plot Phi (°)';
            app.PlotPhiDropDown = uidropdown(app.AntTestSettingsGridLayout);
            app.PlotPhiDropDown.Items = {''};
            app.PlotPhiDropDown.ValueChangedFcn = createCallbackFcn(app, @AntennaSelectedAngleValue, true);
            app.PlotPhiDropDown.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARES

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end